classdef Angleapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulatorUIFigure  matlab.ui.Figure
        ResetButton        matlab.ui.control.Button
        SimulateButton     matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        callingapp
        Cube % Description
    end

    methods (Access = private)

        function Anglecallback(app,~,~)
            array = app.callingapp.getframe(19);
            [frame,status] = Process(array,"in");
%             app.callingapp.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
            if status
                Angle(1)=typecast((uint8(frame( 4: 7))),'single');
                Angle(2)=typecast((uint8(frame( 8:11))),'single');
                Angle(3)=typecast((uint8(frame(12:15))),'single');
                app.dispAngles(Angle);
            end
        end
        function dispAngles(app,angles)
            rotate(app.Cube,[1 0 0],angles(1));
            rotate(app.Cube,[0 1 0],angles(2));
            rotate(app.Cube,[0 0 1],angles(3));
            pause(0.001);
        end
        function draw(app)
            delete(app.Cube);
            a = -pi : pi/2 : pi;                                % Define Corners
            ph = pi/4;                                          % Define Angular Orientation (‘Phase’)
            x = [cos(a+ph); cos(a+ph)]*.5/cos(ph);
            y = [sin(a+ph); sin(a+ph)]*.5/sin(ph);
            z = [zeros(size(a)); ones(size(a))];

            app.Cube(1)=surf(app.UIAxes,x, y, z, 'FaceColor','cyan');                      % Plot Cube
            hold(app.UIAxes,"on");
            z = [-ones(size(a)); zeros(size(a))];
            app.Cube(2)=surf(app.UIAxes,x, y, z, 'FaceColor','cyan');
            z = [-ones(size(a)); ones(size(a))];
            app.Cube(3)=patch(app.UIAxes,x', y', z', 'cyan');                              % Make Cube Appear Solid
            axis(app.UIAxes,[ -1  1    -1  1    -1  1]*.5*2.6);
            hold(app.UIAxes,"off");
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandle)
            app.SimulatorUIFigure.Position = mainapphandle.AnglesPanel.Position;
            app.draw();
            app.callingapp = mainapphandle;
            app.callingapp.AnglesButton.Enable ="off";
            uifigureOnTop(app.SimulatorUIFigure, true);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            command = [0x07,0x01,10];
            app.callingapp.send(command,@app.Anglecallback,19);
        end

        % Close request function: SimulatorUIFigure
        function SimulatorUIFigureCloseRequest(app, event)
            app.callingapp.AnglesHandle = [];
            app.callingapp.AnglesButton.Enable ="on";
            delete(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.draw();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulatorUIFigure = uifigure('Visible', 'off');
            app.SimulatorUIFigure.Name = 'Simulator';
            app.SimulatorUIFigure.Icon = 'Simulator.png';
            app.SimulatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimulatorUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.SimulatorUIFigure);
            title(app.UIAxes, 'Simulator')
            app.SimulateButton = uibutton(app.SimulatorUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ResetButton = uibutton(app.SimulatorUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.SimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Angleapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulatorUIFigure)
        end
    end
end