classdef Gyroscopapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GyroscopeUIFigure  matlab.ui.Figure
        zEditField         matlab.ui.control.EditField
        zEditFieldLabel    matlab.ui.control.Label
        yEditField         matlab.ui.control.EditField
        yEditFieldLabel    matlab.ui.control.Label
        xEditField         matlab.ui.control.EditField
        xEditFieldLabel    matlab.ui.control.Label
        ReadButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        callingapp % Description
        data = [];
    end

    methods (Access = private)
        function GyroCallback(app,~,~)
            array = app.callingapp.getframe(19);

            [frame,status] = Process(array,"in");
%             app.callingapp.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
            if status
                gyros(1)=typecast((uint8(frame( 4: 7))),'single');
                gyros(2)=typecast((uint8(frame( 8:11))),'single');
                gyros(3)=typecast((uint8(frame(12:15))),'single');
                app.dispGyro(gyros);
            end
        end
    end

    methods (Access = public)

        function dispGyro(app,Gyros)
            app.xEditField.Value=num2str(Gyros(1));
            app.yEditField.Value=num2str(Gyros(2));
            app.zEditField.Value=num2str(Gyros(3));
            app.data(end+1,:) = Gyros;
            if size(app.data,1)>1
                plot(app.UIAxes,app.data);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandler)
            app.GyroscopeUIFigure.Position = mainapphandler.GyroPanel.Position;
            
            mainapphandler.GyroscopeButton.Enable = "off";
            app.callingapp = mainapphandler;
            uifigureOnTop(app.GyroscopeUIFigure, true);
        end

        % Close request function: GyroscopeUIFigure
        function GyroscopeUIFigureCloseRequest(app, event)
            app.callingapp.GyroscopeButton.Enable = "on";
            app.callingapp.GyroscopeHandle=[];
            delete(app)
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            command = [0x07,0x01,11];
            app.callingapp.send(command,@app.GyroCallback,19);
            %             command = Process(command,"out");
            %             configureCallback(app.callingapp.Serialdev,"terminator",@app.GyroCallback);
            %             write(app.callingapp.Serialdev,command,"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GyroscopeUIFigure = uifigure('Visible', 'off');
            app.GyroscopeUIFigure.Name = 'Gyroscope';
            app.GyroscopeUIFigure.Icon = 'Gyroscope.png';
            app.GyroscopeUIFigure.CloseRequestFcn = createCallbackFcn(app, @GyroscopeUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.GyroscopeUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ReadButton = uibutton(app.GyroscopeUIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.xEditFieldLabel = uilabel(app.GyroscopeUIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GyroscopeUIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.GyroscopeUIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GyroscopeUIFigure, 'text');
            app.zEditFieldLabel = uilabel(app.GyroscopeUIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GyroscopeUIFigure, 'text');
            % Show the figure after all components are created
            app.GyroscopeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gyroscopapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GyroscopeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GyroscopeUIFigure)
        end
    end
end