classdef Logapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LatchingLabel          matlab.ui.control.Label
        DisableButton          matlab.ui.control.Button
        EnableButton           matlab.ui.control.Button
        ReadLogButton          matlab.ui.control.Button
        MonitoringLabel        matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        CommandsTextArea       matlab.ui.control.TextArea
        CommandsTextAreaLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        callingapp % Description
        counter = 1;
        cellArrayText = [];
    end
    %should be done using iterative method
    methods (Access = public)
        function str = getString(~,frame)
            %
            switch frame(3)
                case 0
                    str = "PING";
                case 1
                    str = "INIT";
                case 2
                    str = "ACK";
                case 3
                    str = "NACK";
%                 case 4
%                 case 5
%                 case 6
%                 case 7
%                 case 8
%                 case 9
%                 case 10
                case 11
                    str = "Get Angles";
                case 12
                    str = "Get Gyro Data";
                case 13
                    str = "Get Magneto Data";
                case 14
                    str = "Get Temperature";
                case 15
                    str = "Get Sun Sensors Data";
                case 16
                    str = "Get Telmetry";
                case 17
                    str = "Change Motors Speed";
%                 case 18
%                 case 19
%                 case 20
%                 case 21
%                 case 22
                case 23
                    str = "Read Log";
                case 24
                    str = "Get Motors Speed";
                case 25
                    str = "Start Latch";
                case 26
                    str = "Stop Latch";
            end
        end
        function dispLog(app,allframes)
            %   [frame,status] = Process(array,"in");
            app.counter = 1;
            app.cellArrayText = [];
            FENDindeces = find(allframes ==0xc0);
            for i=2:2:length(FENDindeces)
                [frame,status] =  Process(allframes(FENDindeces(i-1):FENDindeces(i)),"in");
                if status
                    app.cellArrayText(app.counter) = sprintf(app.counter+" - " +app.getString(frame));
                else
                    app.cellArrayText(app.counter) = sprintf(app.counter+" - " +"CRC ERROR");
                end
                app.counter = app.counter +1;
            end
            app.CommandsTextArea.Value = app.cellArrayText;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandler)
            app.callingapp = mainapphandler;
            mainapphandler.LogButton.Enable = "off";
        end

        % Button pushed function: ReadLogButton
        function ReadLogButtonPushed(app, event)
            command = [0x07,0x01,22,0];
            command = Process(command,"out");
            app.counter = 1;
            app.cellArrayText = {};
            configureCallback(app.callingapp.Serialdev,"off");
            write(app.callingapp.Serialdev,command,"uint8");
            pause(0.1);
            allframes = read(app.callingapp.Serialdev,app.callingapp.NumBytesAvailable,"uint8");
            app.dispLog(allframes);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            app.callingapp.LogButton.Enable = "on";
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CommandsTextAreaLabel = uilabel(app.UIFigure);
            app.CommandsTextAreaLabel.Text = 'Commands';
            app.CommandsTextArea = uitextarea(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Stat'; 'Value'};
            app.UITable.RowName = {'''numbered'''};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.MonitoringLabel = uilabel(app.UIFigure);
            app.MonitoringLabel.Text = 'Monitoring';
            app.ReadLogButton = uibutton(app.UIFigure, 'push');
            app.ReadLogButton.ButtonPushedFcn = createCallbackFcn(app, @ReadLogButtonPushed, true);
            app.ReadLogButton.Text = 'Read Log';
            app.EnableButton = uibutton(app.UIFigure, 'push');
            app.EnableButton.Text = 'Enable';
            app.DisableButton = uibutton(app.UIFigure, 'push');
            app.DisableButton.Text = 'Disable';
            app.LatchingLabel = uilabel(app.UIFigure);
            app.LatchingLabel.Text = 'Latching';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Logapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end