classdef Memoryapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MemoryUIFigure            matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        WriteTab                  matlab.ui.container.Tab
        x080E00000x080FFFFFLabel  matlab.ui.control.Label
        EraseButton               matlab.ui.control.Button
        LockButton                matlab.ui.control.Button
        UnlockButton              matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        WriteButton               matlab.ui.control.Button
        ValueEditField            matlab.ui.control.EditField
        ValueEditFieldLabel       matlab.ui.control.Label
        AddressEditField          matlab.ui.control.EditField
        AddressEditFieldLabel     matlab.ui.control.Label
        ReadTab                   matlab.ui.container.Tab
        ValueEditField_2          matlab.ui.control.EditField
        ValueEditField_2Label     matlab.ui.control.Label
        AddressEditField_2        matlab.ui.control.EditField
        AddressEditField_2Label   matlab.ui.control.Label
        ReadButton                matlab.ui.control.Button
    end


    properties (Access = private)
        callingapp % Description
    end

    methods (Access = private)

        function memorywrite(app,~,~)
            array = app.callingapp.getframe(7);
            [~,status] = Process(array,"in");
            if status
                app.Label.Text = "Sent";
            end
        end
        function memoryread(app,~,~)
            array = app.callingapp.getframe(11);

            [frame,status] = Process(array,"in");
            if status
                num = uint8(frame(4:7));
                z = typecast(num,'uint32');
                app.ValueEditField_2.Value = dec2hex(z,8);
            end

        end
        function unlock(app,~,~)
            array = app.callingapp.getframe(7);
            [~,status] = Process(array,"in");
            if status
                app.Label.Text = "unlocked";
            end
        end
        function lock(app,~,~)
            array = app.callingapp.getframe(7);
            [~,status] = Process(array,"in");
            if status
                app.Label.Text = "locked";
            end
        end
        function erase(app,~,~)
            array = app.callingapp.getframe(7);
            [~,status] = Process(array,"in");
            if status
                app.Label.Text = "erased";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandler)
            
            app.MemoryUIFigure.Position = mainapphandler.MemoryPanel.Position;
            app.callingapp = mainapphandler;
            mainapphandler.MemoryButton.Enable = "off";
            uifigureOnTop(app.MemoryUIFigure, true);
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            str = app.AddressEditField.Value;
            num = hex2num(str);
            arr = typecast(num,'uint8');
            address = arr(5:8);
            str = app.ValueEditField.Value;
            num = hex2num(str);
            arr = typecast(num,'uint8');
            val = arr(5:8);
            command = [0x07,0x01,17 address val];
            app.callingapp.send(command,@app.memorywrite,7);
        end

        % Close request function: MemoryUIFigure
        function MemoryUIFigureCloseRequest(app, event)
            app.callingapp.LogHandle=[];
            app.callingapp.MemoryButton.Enable = "on";
            delete(app)

        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            str = app.AddressEditField_2.Value;
            num = hex2num(str);
            arr = typecast(num,'uint8');
            address = arr(5:8);
            command = [0x07,0x01,18 address];
            app.callingapp.send(command,@app.memoryread,11);
        end

        % Button pushed function: UnlockButton
        function UnlockButtonPushed(app, event)
            command = [0x07,0x01,20 ];
            app.callingapp.send(command,@app.unlock,7);
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            command = [0x07,0x01,21 ];
            app.callingapp.send(command,@app.lock,7);
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
            command = [0x07,0x01,19 11];
            app.callingapp.send(command,@app.erase,7);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MemoryUIFigure = uifigure('Visible', 'off');
            app.MemoryUIFigure.Name = 'Memory';
            app.MemoryUIFigure.Icon = 'Memory.png';
            app.MemoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @MemoryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MemoryUIFigure);
            app.WriteTab = uitab(app.TabGroup);
            app.WriteTab.Title = 'Write';
            app.AddressEditFieldLabel = uilabel(app.WriteTab);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.WriteTab, 'text');
            app.ValueEditFieldLabel = uilabel(app.WriteTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.WriteTab, 'text');
            app.WriteButton = uibutton(app.WriteTab, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.Label = uilabel(app.WriteTab);
            app.Label.Text = '';
            app.UnlockButton = uibutton(app.WriteTab, 'push');
            app.UnlockButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockButtonPushed, true);
            app.UnlockButton.Text = 'Unlock';
            app.LockButton = uibutton(app.WriteTab, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Text = 'Lock';
            app.EraseButton = uibutton(app.WriteTab, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Text = 'Erase';
            app.x080E00000x080FFFFFLabel = uilabel(app.WriteTab);
            app.x080E00000x080FFFFFLabel.Text = '0x080E 0000  :  0x080F FFFF';
            app.ReadTab = uitab(app.TabGroup);
            app.ReadTab.Title = 'Read';
            app.ReadButton = uibutton(app.ReadTab, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.AddressEditField_2Label = uilabel(app.ReadTab);
            app.AddressEditField_2Label.Text = 'Address';
            app.AddressEditField_2 = uieditfield(app.ReadTab, 'text');
            app.ValueEditField_2Label = uilabel(app.ReadTab);
            app.ValueEditField_2Label.Text = 'Value';
            app.ValueEditField_2 = uieditfield(app.ReadTab, 'text');
            % Show the figure after all components are created
            app.MemoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Memoryapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MemoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MemoryUIFigure)
        end
    end
end