classdef Motorsapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotorsUIFigure          matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        SettingTab              matlab.ui.container.Tab
        Label                   matlab.ui.control.Label
        SetMotor3EditField      matlab.ui.control.NumericEditField
        Motor3EditField_2Label  matlab.ui.control.Label
        SetMotor2EditField      matlab.ui.control.NumericEditField
        Motor2EditField_2Label  matlab.ui.control.Label
        SetMotor1EditField      matlab.ui.control.NumericEditField
        Motor1EditField_2Label  matlab.ui.control.Label
        ChangeSpeedButton       matlab.ui.control.Button
        Motor3Knob              matlab.ui.control.Knob
        Motor3KnobLabel         matlab.ui.control.Label
        Motor2Knob              matlab.ui.control.Knob
        Motor2KnobLabel         matlab.ui.control.Label
        Motor1Knob              matlab.ui.control.Knob
        Motor1KnobLabel         matlab.ui.control.Label
        GettingTab              matlab.ui.container.Tab
        GetSpeedButton          matlab.ui.control.Button
        Motor3EditField         matlab.ui.control.NumericEditField
        Motor3EditFieldLabel    matlab.ui.control.Label
        Motor2EditField         matlab.ui.control.NumericEditField
        Motor2EditFieldLabel    matlab.ui.control.Label
        Motor1EditField         matlab.ui.control.NumericEditField
        Motor1EditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        callingapp % Description
    end

    methods (Access = private)

        function MotorsSetCallback(app,~,~)
            array = app.callingapp.getframe(7);
            [frame,status] = Process(array,"in");
            if status
                if(frame(3) == 0x02)
                    app.Label.FontColor = 'g';
                    app.Label.Text = "Success";
                else
                    app.Label.FontColor = 'r';
                    app.Label.Text = "failed";
                end
            end
        end

        function MotorsGetCallback(app,~,~)
            array = app.callingapp.getframe(10);
            EditFields = [app.Motor1EditField , app.Motor2EditField , app.Motor3EditField];
            [frame,status] = Process(array,"in");
%             app.callingapp.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
            if status
                for i =1:3
                    if frame(i + 3) >= 128
                        temp = int8(frame(i + 3) - 128);
                        temp = -temp;
                        EditFields(i).Value = double(temp);
                    else
                        EditFields(i).Value = double(frame(i + 3));
                    end

                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.MotorsUIFigure.Position = mainapp.MotorsPanel.Position;
            app.callingapp = mainapp;
            app.callingapp.MotorsButton.Enable = "off";
            uifigureOnTop(app.MotorsUIFigure, true);
        end

        % Value changed function: SetMotor1EditField
        function SetMotor1EditFieldValueChanged(app, event)
            value = app.SetMotor1EditField.Value;
            app.Motor1Knob.Value = (value);
        end

        % Value changed function: SetMotor2EditField
        function SetMotor2EditFieldValueChanged(app, event)
            value = app.SetMotor2EditField.Value;
            app.Motor2Knob.Value = (value);
        end

        % Value changed function: SetMotor3EditField
        function SetMotor3EditFieldValueChanged(app, event)
            value = app.SetMotor3EditField.Value;
            app.Motor3Knob.Value = (value);
        end

        % Value changing function: Motor1Knob
        function Motor1KnobValueChanging(app, event)
            changingValue = double(int8(event.Value));
            app.SetMotor1EditField.Value = (changingValue);
        end

        % Value changing function: Motor2Knob
        function Motor2KnobValueChanging(app, event)
            changingValue = double(int8(event.Value));
            app.SetMotor2EditField.Value = (changingValue);
        end

        % Value changing function: Motor3Knob
        function Motor3KnobValueChanging(app, event)
            changingValue = double(int8(event.Value));
            app.SetMotor3EditField.Value = (changingValue);
        end

        % Button pushed function: ChangeSpeedButton
        function ChangeSpeedButtonPushed(app, event)
            scale = [0x07 0x01 16 0 0 0];
            m(1) = app.SetMotor1EditField.Value;
            m(2) = app.SetMotor2EditField.Value;
            m(3) = app.SetMotor3EditField.Value;
            for i =4:6
                if m(i-3) <  0
                    temp = int8(m(i-3));
                    temp = -temp;
                    scale(i) = uint8(temp) + 128;
                else
                    scale(i) = m(i-3);
                end
            end
            app.callingapp.send(scale,@app.MotorsSetCallback,7);
            %             command = Process(scale,"out");
            %             configureCallback(app.callingapp.Serialdev,"terminator",@app.MotorsSetCallback);
            %             write(app.callingapp.Serialdev,command,"uint8");
        end

        % Close request function: MotorsUIFigure
        function MotorsUIFigureCloseRequest(app, event)
            app.callingapp.MotorsButton.Enable = "on";
            app.callingapp.MotorsHandle=[];
            delete(app)

        end

        % Button pushed function: GetSpeedButton
        function GetSpeedButtonPushed(app, event)
            scale = [0x07 0x01 23];
            app.callingapp.send(scale,@app.MotorsGetCallback,10);
            %             command = Process(scale,"out");
            %             configureCallback(app.callingapp.Serialdev,"terminator",@app.MotorsGetCallback);
            %             write(app.callingapp.Serialdev,command,"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotorsUIFigure = uifigure('Visible', 'off');
            app.MotorsUIFigure.Name = 'Motors';
            app.MotorsUIFigure.Icon = 'Motors.png';
            app.MotorsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MotorsUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MotorsUIFigure);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.Motor1KnobLabel = uilabel(app.SettingTab);
            app.Motor1KnobLabel.Text = 'Motor1';
            app.Motor1Knob = uiknob(app.SettingTab, 'continuous');
            app.Motor1Knob.Limits = [-100 100];
            app.Motor1Knob.ValueChangingFcn = createCallbackFcn(app, @Motor1KnobValueChanging, true);
            app.Motor2KnobLabel = uilabel(app.SettingTab);
            app.Motor2KnobLabel.Text = 'Motor2';
            app.Motor2Knob = uiknob(app.SettingTab, 'continuous');
            app.Motor2Knob.Limits = [-100 100];
            app.Motor2Knob.ValueChangingFcn = createCallbackFcn(app, @Motor2KnobValueChanging, true);
            app.Motor3KnobLabel = uilabel(app.SettingTab);
            app.Motor3KnobLabel.Text = 'Motor3';
            app.Motor3Knob = uiknob(app.SettingTab, 'continuous');
            app.Motor3Knob.Limits = [-100 100];
            app.Motor3Knob.ValueChangingFcn = createCallbackFcn(app, @Motor3KnobValueChanging, true);
            app.ChangeSpeedButton = uibutton(app.SettingTab, 'push');
            app.ChangeSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSpeedButtonPushed, true);
            app.ChangeSpeedButton.Text = 'Change Speed';
            app.Motor1EditField_2Label = uilabel(app.SettingTab);
            app.Motor1EditField_2Label.Text = 'Motor 1';
            app.SetMotor1EditField = uieditfield(app.SettingTab, 'numeric');
            app.SetMotor1EditField.Limits = [-100 100];
            app.SetMotor1EditField.ValueDisplayFormat = '%.0f';
            app.SetMotor1EditField.ValueChangedFcn = createCallbackFcn(app, @SetMotor1EditFieldValueChanged, true);
            app.Motor2EditField_2Label = uilabel(app.SettingTab);
            app.Motor2EditField_2Label.Text = 'Motor 2';
            app.SetMotor2EditField = uieditfield(app.SettingTab, 'numeric');
            app.SetMotor2EditField.Limits = [-100 100];
            app.SetMotor2EditField.ValueDisplayFormat = '%.0f';
            app.SetMotor2EditField.ValueChangedFcn = createCallbackFcn(app, @SetMotor2EditFieldValueChanged, true);
            app.Motor3EditField_2Label = uilabel(app.SettingTab);
            app.Motor3EditField_2Label.Text = 'Motor3';
            app.SetMotor3EditField = uieditfield(app.SettingTab, 'numeric');
            app.SetMotor3EditField.Limits = [-100 100];
            app.SetMotor3EditField.ValueDisplayFormat = '%.0f';
            app.SetMotor3EditField.ValueChangedFcn = createCallbackFcn(app, @SetMotor3EditFieldValueChanged, true);
            app.Label = uilabel(app.SettingTab);
            app.Label.Text = '';
            app.GettingTab = uitab(app.TabGroup);
            app.GettingTab.Title = 'Getting';
            app.Motor1EditFieldLabel = uilabel(app.GettingTab);
            app.Motor1EditFieldLabel.Text = 'Motor1';
            app.Motor1EditField = uieditfield(app.GettingTab, 'numeric');
            app.Motor1EditField.Limits = [-100 100];
            app.Motor1EditField.ValueDisplayFormat = '%.0f';
            app.Motor2EditFieldLabel = uilabel(app.GettingTab);
            app.Motor2EditFieldLabel.Text = 'Motor2';
            app.Motor2EditField = uieditfield(app.GettingTab, 'numeric');
            app.Motor2EditField.Limits = [-100 100];
            app.Motor2EditField.ValueDisplayFormat = '%.0f';
            app.Motor3EditFieldLabel = uilabel(app.GettingTab);
            app.Motor3EditFieldLabel.Text = 'Motor3';
            app.Motor3EditField = uieditfield(app.GettingTab, 'numeric');
            app.Motor3EditField.Limits = [-100 100];
            app.Motor3EditField.ValueDisplayFormat = '%.0f';
            app.GetSpeedButton = uibutton(app.GettingTab, 'push');
            app.GetSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @GetSpeedButtonPushed, true);
            app.GetSpeedButton.Text = 'Get Speed';
            % Show the figure after all components are created
            app.MotorsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motorsapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotorsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotorsUIFigure)
        end
    end
end