classdef Sunsensorsapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SunSensorsUIFigure  matlab.ui.Figure
        ReadButton          matlab.ui.control.Button
        SS5EditField        matlab.ui.control.EditField
        SS5EditFieldLabel   matlab.ui.control.Label
        SS4EditField        matlab.ui.control.EditField
        SS4EditFieldLabel   matlab.ui.control.Label
        SS3EditField        matlab.ui.control.EditField
        SS3EditFieldLabel   matlab.ui.control.Label
        SS2EditField        matlab.ui.control.EditField
        SS2EditFieldLabel   matlab.ui.control.Label
        SS1EditField        matlab.ui.control.EditField
        SS1EditFieldLabel   matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        callingapp % Description
        data = [];
    end
    methods (Access = public)

        function dispSunsensors(app,ssdata)
            app.SS1EditField.Value=num2str(ssdata(1));
            app.SS2EditField.Value=num2str(ssdata(2));
            app.SS3EditField.Value=num2str(ssdata(3));
            app.SS4EditField.Value=num2str(ssdata(4));
            app.SS5EditField.Value=num2str(ssdata(5));
            app.data(end+1,:) = ssdata;
            if size(app.data,1)>1
                plot(app.UIAxes,app.data);
            end

        end
    end
    methods (Access = private)

        function SunsensorCallback(app,~,~)
            array = app.callingapp.getframe(27);
%             app.callingapp.msgLabel.Text = reshape(dec2hex(array,2)',1,[]);
            [frame,status] = Process(array,"in");
            if status
                ss =[];

                ss(1)=typecast((uint8(frame( 4: 7))),'single');
                ss(2)=typecast((uint8(frame( 8:11))),'single');
                ss(3)=typecast((uint8(frame(12:15))),'single');
                ss(4)=typecast((uint8(frame(16:19))),'single');
                ss(5)=typecast((uint8(frame(20:23))),'single');
                app.dispSunsensors(ss);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandler)
            
            app.SunSensorsUIFigure.Position = mainapphandler.SunSensorsPanel.Position;
            app.callingapp = mainapphandler;
            app.callingapp.SunsensorButton.Enable = "off";
            uifigureOnTop(app.SunSensorsUIFigure, true);
        end

        % Close request function: SunSensorsUIFigure
        function SunSensorsUIFigureCloseRequest(app, event)
            app.callingapp.SunsensorButton.Enable = "on";
            app.callingapp.SunsensorsHandle=[];
            delete(app)
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            command = [0x07,0x01,14];
            app.callingapp.send(command,@app.SunsensorCallback,27);
            %             command = Process(command,"out");
            %             configureCallback(app.callingapp.Serialdev,"terminator",@app.SunsensorCallback);
            %             write(app.callingapp.Serialdev,command,"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SunSensorsUIFigure = uifigure('Visible', 'off');
            app.SunSensorsUIFigure.Name = 'Sun Sensors';
            app.SunSensorsUIFigure.Icon = 'SunSensors.png';
            app.SunSensorsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SunSensorsUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.SunSensorsUIFigure);
            app.UIAxes.LineStyleOrder = {'-'};
            app.SS1EditFieldLabel = uilabel(app.SunSensorsUIFigure);
            app.SS1EditFieldLabel.Text = 'SS1';
            app.SS1EditField = uieditfield(app.SunSensorsUIFigure, 'text');
            app.SS2EditFieldLabel = uilabel(app.SunSensorsUIFigure);
            app.SS2EditFieldLabel.Text = 'SS2';
            app.SS2EditField = uieditfield(app.SunSensorsUIFigure, 'text');
            app.SS3EditFieldLabel = uilabel(app.SunSensorsUIFigure);
            app.SS3EditFieldLabel.Text = 'SS3';
            app.SS3EditField = uieditfield(app.SunSensorsUIFigure, 'text');
            app.SS4EditFieldLabel = uilabel(app.SunSensorsUIFigure);
            app.SS4EditFieldLabel.Text = 'SS4';
            app.SS4EditField = uieditfield(app.SunSensorsUIFigure, 'text');
            app.SS5EditFieldLabel = uilabel(app.SunSensorsUIFigure);
            app.SS5EditFieldLabel.Text = 'SS5';
            app.SS5EditField = uieditfield(app.SunSensorsUIFigure, 'text');
            app.ReadButton = uibutton(app.SunSensorsUIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            % Show the figure after all components are created
            app.SunSensorsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sunsensorsapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SunSensorsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SunSensorsUIFigure)
        end
    end
end