classdef Temperatureapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TemperatureUIFigure    matlab.ui.Figure
        ReadTemperatureButton  matlab.ui.control.Button
        EditField              matlab.ui.control.NumericEditField
        Gauge                  matlab.ui.control.Gauge
    end


    properties (Access = private)
        callingapp
    end

    methods (Access = private)

        function TempCallback(app,~,~)
          
            array = app.callingapp.getframe(11);
            
            [frame,status] = Process(array,"in");
            if status
%                 app.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
                app.dispTemp(typecast((uint8(frame( 4: 7))),'single'));
            end
        end

    end
    
    methods (Access = public)
        
        function dispTemp(app,temperature)
            app.Gauge.Value=temperature;
            app.EditField.Value = double(temperature);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainappHandler)
            
            app.TemperatureUIFigure.Position = mainappHandler.TempPanel.Position;
            mainappHandler.TempButton.Enable = "off";
            app.callingapp = mainappHandler;
            uifigureOnTop(app.TemperatureUIFigure, true);
        end

        % Close request function: TemperatureUIFigure
        function TemperatureUIFigureCloseRequest(app, event)
            app.callingapp.TempButton.Enable = "on";
            app.callingapp.TemperatureHandle=[];
            delete(app)

        end

        % Button pushed function: ReadTemperatureButton
        function ReadTemperatureButtonPushed(app, event)
            command = [0x07,0x01,13];
            app.callingapp.send(command,@app.TempCallback,11);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TemperatureUIFigure = uifigure('Visible', 'off');
            app.TemperatureUIFigure.Name = 'Temperature';
            app.TemperatureUIFigure.Icon = 'Temperature.png';
            app.TemperatureUIFigure.CloseRequestFcn = createCallbackFcn(app, @TemperatureUIFigureCloseRequest, true);
            app.Gauge = uigauge(app.TemperatureUIFigure, 'circular');
            app.EditField = uieditfield(app.TemperatureUIFigure, 'numeric');
            app.ReadTemperatureButton = uibutton(app.TemperatureUIFigure, 'push');
            app.ReadTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTemperatureButtonPushed, true);
            app.ReadTemperatureButton.Text = 'Read Temperature';
            % Show the figure after all components are created
            app.TemperatureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Temperatureapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TemperatureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TemperatureUIFigure)
        end
    end
end