classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure              matlab.ui.Figure
        AnglesPanel               matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
        ResetButton               matlab.ui.control.Button
        SimulateButton            matlab.ui.control.Button
        SimulatorUIFigure         matlab.ui.control.UIAxes
        GyroPanel                 matlab.ui.container.Panel
        gyroWIALabel              matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        gyrozEditField            matlab.ui.control.EditField
        zEditFieldLabel           matlab.ui.control.Label
        gyroyEditField            matlab.ui.control.EditField
        yEditFieldLabel           matlab.ui.control.Label
        gyroxEditField            matlab.ui.control.EditField
        xEditFieldLabel           matlab.ui.control.Label
        gyroReadButton            matlab.ui.control.Button
        gyroUIAxes                matlab.ui.control.UIAxes
        LogPanel                  matlab.ui.container.Panel
        logReadButton             matlab.ui.control.Button
        CommandsTextArea          matlab.ui.control.TextArea
        MemoryPanel               matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        WriteTab                  matlab.ui.container.Tab
        x080E00000x080FFFFFLabel  matlab.ui.control.Label
        EraseButton               matlab.ui.control.Button
        LockButton                matlab.ui.control.Button
        UnlockButton              matlab.ui.control.Button
        memLabel                  matlab.ui.control.Label
        WriteButton               matlab.ui.control.Button
        ValueEditField            matlab.ui.control.EditField
        ValueEditFieldLabel       matlab.ui.control.Label
        AddressEditField          matlab.ui.control.EditField
        AddressEditFieldLabel     matlab.ui.control.Label
        ReadTab                   matlab.ui.container.Tab
        ValueEditField_2          matlab.ui.control.EditField
        ValueEditField_2Label     matlab.ui.control.Label
        AddressEditField_2        matlab.ui.control.EditField
        AddressEditField_2Label   matlab.ui.control.Label
        memReadButton             matlab.ui.control.Button
        MotorsPanel               matlab.ui.container.Panel
        TabGroup_2                matlab.ui.container.TabGroup
        SettingTab                matlab.ui.container.Tab
        ChangespeedandreadButton  matlab.ui.control.Button
        MotorsLabel               matlab.ui.control.Label
        SetMotor3EditField        matlab.ui.control.NumericEditField
        Motor3EditField_2Label    matlab.ui.control.Label
        SetMotor2EditField        matlab.ui.control.NumericEditField
        Motor2EditField_2Label    matlab.ui.control.Label
        SetMotor1EditField        matlab.ui.control.NumericEditField
        Motor1EditField_2Label    matlab.ui.control.Label
        ChangeSpeedButton         matlab.ui.control.Button
        Motor3Knob                matlab.ui.control.Knob
        Motor3KnobLabel           matlab.ui.control.Label
        Motor2Knob                matlab.ui.control.Knob
        Motor2KnobLabel           matlab.ui.control.Label
        Motor1Knob                matlab.ui.control.Knob
        Motor1KnobLabel           matlab.ui.control.Label
        GettingTab                matlab.ui.container.Tab
        GetSpeedButton            matlab.ui.control.Button
        Motor3EditField           matlab.ui.control.NumericEditField
        Motor3EditFieldLabel      matlab.ui.control.Label
        Motor2EditField           matlab.ui.control.NumericEditField
        Motor2EditFieldLabel      matlab.ui.control.Label
        Motor1EditField           matlab.ui.control.NumericEditField
        Motor1EditFieldLabel      matlab.ui.control.Label
        SunSensorsPanel           matlab.ui.container.Panel
        Image4                    matlab.ui.control.Image
        SS4EditField              matlab.ui.control.EditField
        SS4EditFieldLabel         matlab.ui.control.Label
        SS3EditField              matlab.ui.control.EditField
        SS3EditFieldLabel         matlab.ui.control.Label
        SS2EditField              matlab.ui.control.EditField
        SS2EditFieldLabel         matlab.ui.control.Label
        SS1EditField              matlab.ui.control.EditField
        SS1EditFieldLabel         matlab.ui.control.Label
        SSReadButton              matlab.ui.control.Button
        SSUIAxes                  matlab.ui.control.UIAxes
        MagnetoPanel              matlab.ui.container.Panel
        magnetoWIALabel           matlab.ui.control.Label
        Image3                    matlab.ui.control.Image
        magnetozEditField         matlab.ui.control.EditField
        zEditFieldLabel_2         matlab.ui.control.Label
        magnetoyEditField         matlab.ui.control.EditField
        yEditFieldLabel_2         matlab.ui.control.Label
        magnetoxEditField         matlab.ui.control.EditField
        xEditFieldLabel_2         matlab.ui.control.Label
        magnetoReadButton         matlab.ui.control.Button
        magnetoUIAxes             matlab.ui.control.UIAxes
        TempPanel                 matlab.ui.container.Panel
        Image5                    matlab.ui.control.Image
        TemperatureLabel          matlab.ui.control.Label
        ReadTemperatureButton     matlab.ui.control.Button
        tempEditField             matlab.ui.control.NumericEditField
        Gauge                     matlab.ui.control.Gauge
        APPsPanel                 matlab.ui.container.Panel
        Payload                   matlab.ui.control.Button
        msLabel                   matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        Button                    matlab.ui.control.Button
        getTeleButton             matlab.ui.control.Button
        MotorsButton              matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        PingButton                matlab.ui.control.Button
        TelemetryButton           matlab.ui.control.Button
        TempButton                matlab.ui.control.Button
        AnglesButton              matlab.ui.control.Button
        LatchButton               matlab.ui.control.Button
        LogButton                 matlab.ui.control.Button
        MemoryButton              matlab.ui.control.Button
        SunsensorButton           matlab.ui.control.Button
        MagnetometerButton        matlab.ui.control.Button
        GyroscopeButton           matlab.ui.control.Button
        ConnectionPanel           matlab.ui.container.Panel
        SerialstateLabel          matlab.ui.control.Label
        StatusLabel               matlab.ui.control.Label
        ConnectionLabel           matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
        UpdateSerialButton        matlab.ui.control.Button
        BaudRateEditField         matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel    matlab.ui.control.Label
        SerialPortDropDown        matlab.ui.control.DropDown
        SerialPortDropDownLabel   matlab.ui.control.Label
    end

    properties (Access = public)
        Serialdev % the serial port to which we connect
        isLatched = 0
        timerx

        openpanelstable
        %% Angles %%
        Cube
        %% Gyro %%
        gyrodata = [];
        gyroWIA
        %% Magneto %%
        magnetodata = [];
        magnetoWIA
        %% SS %%
        SSdata = [];
        %% Log %%
        counter = 1;
        cellArrayText = [];
        
        %% angles %%
        prevangles = [0,0,0];
        %% combine %%
        timer_combine
    end

    methods (Access = public)
        function add(app,panel)
            app.openpanelstable{end+1} = panel;
        end
        function remove(app,panel)
            app.openpanelstable([app.openpanelstable{:}] == panel) = [];
        end
        function rearrange(app)
            switch length(app.openpanelstable)
                case 1
                    app.openpanelstable{1}.Position = [580 242 384 358];
                case 2
                    app.openpanelstable{1}.Position = [258.66 242 384 358];
                    app.openpanelstable{2}.Position = [901.33 242 384 358];
                case 3
                    app.openpanelstable{1}.Position = [98 242 384 358];
                    app.openpanelstable{2}.Position = [580 242 384 358];
                    app.openpanelstable{3}.Position = [1062 242 384 358];
                case 4

                    app.openpanelstable{1}.Position = [4,358,384,358];
                    app.openpanelstable{2}.Position = [388,0,384,358];
                    app.openpanelstable{3}.Position = [772,358,384,358];
                    app.openpanelstable{4}.Position = [1156,0,384,358];
                case 5
                    app.openpanelstable{1}.Position = [98,358,384,358];
                    app.openpanelstable{2}.Position = [258.66,0,384,358];
                    app.openpanelstable{3}.Position = [580,358,384,358];
                    app.openpanelstable{4}.Position = [901.33,0,384,358];
                    app.openpanelstable{5}.Position = [1062,358,384,358];
                case 6
                    app.openpanelstable{1}.Position = [98,358,384,358];
                    app.openpanelstable{2}.Position = [98,0,384,358];
                    app.openpanelstable{3}.Position = [580,358,384,358];
                    app.openpanelstable{4}.Position = [580,0,384,358];
                    app.openpanelstable{5}.Position = [1062,358,384,358];
                    app.openpanelstable{6}.Position = [1062,0,384,358];
                case 7
                    app.openpanelstable{1}.Position = [4,358,384,358];
                    app.openpanelstable{2}.Position = [98,0,384,358];
                    app.openpanelstable{3}.Position = [388,358,384,358];
                    app.openpanelstable{4}.Position = [580,0,384,358];
                    app.openpanelstable{5}.Position = [772,358,384,358];
                    app.openpanelstable{6}.Position = [1062,0,384,358];
                    app.openpanelstable{7}.Position = [1156,358,384,358];
                case 8
                    app.openpanelstable{1}.Position = [4,358,384,358];
                    app.openpanelstable{2}.Position = [4,0,384,358];
                    app.openpanelstable{3}.Position = [388,358,384,358];
                    app.openpanelstable{4}.Position = [388,0,384,358];
                    app.openpanelstable{5}.Position = [772,358,384,358];
                    app.openpanelstable{6}.Position = [772,0,384,358];
                    app.openpanelstable{7}.Position = [1156,358,384,358];
                    app.openpanelstable{8}.Position = [1156,0,384,358];
            end
        end
        function array = getframe(app,num)     % this function is used to check the call back system
            try
                array = read( app.Serialdev,num,"uint8");
                while array(end) ~= 0xc0
                    pause(0.01);
                    array(end+1)= read( app.Serialdev,1,"uint8"); %#ok<AGROW>
                end
            catch
                array = read( app.Serialdev,app.Serialdev.BytesAvailable,"uint8");
            end
        end
        function send(app,command,callback,num)
            try
            flush(app.Serialdev);
            command = Process(command,"out");
            configureCallback(app.Serialdev,"byte",num,callback);
            write(app.Serialdev,command,"uint8");
            catch
            end
        end
    end
    methods (Access = private)
        function PingCallback(app,~,~)
            app.EditField.Value = (toc *1000);
            array = getframe(app,7);
            [frame,status] = Process(array,"in");
            if status == 1 && frame(3) == 0x02
                app.Lamp.Color = 'g';

                app.Lamp.Visible = 'on';
                app.EditField.Visible = 1;
                app.msLabel.Visible=1;

                app.timerx = timer('TimerFcn',@(~,~)app.TimerCallback,'StartDelay',5);
                start(app.timerx);
            else
                app.Lamp.Color = 'r';
                app.Lamp.Visible = 'on';
            end
        end
        function LatchCallback(app,~,~)
            array = getframe(app,7);
            [~,status] = Process(array,"in");
            if status
                if app.isLatched
                    % got ack after start latch
                    app.LatchButton.Icon = 'Latch-open.png';
                else
                    app.LatchButton.Icon = 'Latch-close.png';
                end
            end
        end
        function TelmetryCallback(app,~,~)
            array = getframe(app,63);
            [frame,status] = Process(array,"in");
            if status
                Telmetry = zeros(15,"single");
                for i=7:4:59
                    Telmetry((i-3)/4) = typecast((uint8(frame( i-3: i))),'single');
                end
                if app.GyroPanel.Visible
                    app.dispGyro(Telmetry(1:3));
                end
                if app.MagnetoPanel.Visible
                    app.dispMagneto(Telmetry(4:6));
                end
                if app.TempPanel.Visible
                    app.dispTemp(Telmetry(7));
                end
                if app.SunSensorsPanel.Visible
                    app.dispSunsensors(Telmetry(8:11));
                end
                if app.AnglesPanel.Visible
                    app.dispAngles(Telmetry(12:14));
                end
            end
        end
        function TimerCallback(app,~,~)
            app.Lamp.Visible = 0;
            app.EditField.Visible = 0;
            app.msLabel.Visible=0;
        end
        function TimerCallback2(app,~,~)
            app.APPsPanel.Visible = "on";
            app.ConnectionPanel.Visible = "off";
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         Angles          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function Anglecallback(app,~,~)
            array = app.getframe(19);
            [frame,status] = Process(array,"in");
            %             app.callingapp.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
            if status
                Angle(1)=typecast((uint8(frame( 4: 7))),'single');
                Angle(2)=typecast((uint8(frame( 8:11))),'single');
                Angle(3)=typecast((uint8(frame(12:15))),'single');
                app.dispAngles(Angle);
            end
        end
        function dispAngles(app,angles)
            tempVal = angles-app.prevangles;
            
 
            rotate(app.Cube,[1 0 0],tempVal(1));
            rotate(app.Cube,[0 1 0],tempVal(2));
             rotate(app.Cube,[0 0 1],tempVal(3));
            app.prevangles = angles;
            
        end
        function draw(app)
            delete(app.Cube);
            a = -pi : pi/2 : pi;                                % Define Corners
            ph = pi/4;                                          % Define Angular Orientation (‘Phase’)
            x = [cos(a+ph); cos(a+ph)]*.5/cos(ph);
            y = [sin(a+ph); sin(a+ph)]*.5/sin(ph);
            z = [zeros(size(a)); ones(size(a))];

            app.Cube(1)=surf(app.SimulatorUIFigure,x, y, z, 'FaceColor','cyan');                      % Plot Cube
            hold(app.SimulatorUIFigure,"on");
            z = [-ones(size(a)); zeros(size(a))];
            app.Cube(2)=surf(app.SimulatorUIFigure,x, y, z, 'FaceColor','cyan');
            z = [-ones(size(a)); ones(size(a))];
            app.Cube(3)=patch(app.SimulatorUIFigure,x', y', z', 'cyan');                              % Make Cube Appear Solid
            axis(app.SimulatorUIFigure,[ -1  1    -1  1    -1  1]*.5*2.6);
            hold(app.SimulatorUIFigure,"off");
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         Gyro            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function GyroCallback(app,~,~)
            array = app.getframe(19);

            [frame,status] = Process(array,"in");
            %             app.callingapp.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
            if status
                gyros(1)=typecast((uint8(frame( 4: 7))),'single');
                gyros(2)=typecast((uint8(frame( 8:11))),'single');
                gyros(3)=typecast((uint8(frame(12:15))),'single');
                app.dispGyro(gyros);
            end
        end
        function dispGyro(app,Gyros)
            app.gyroxEditField.Value=num2str(Gyros(1));
            app.gyroyEditField.Value=num2str(Gyros(2));
            app.gyrozEditField.Value=num2str(Gyros(3));
            app.gyrodata(end+1,:) = Gyros;
            if size(app.gyrodata,1)>1
                plot(app.gyroUIAxes,app.gyrodata);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         Magneto         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function dispMagneto(app,Magnetos)
            app.magnetoxEditField.Value=num2str(Magnetos(1));
            app.magnetoyEditField.Value=num2str(Magnetos(2));
            app.magnetozEditField.Value=num2str(Magnetos(3));
            app.magnetodata(end+1,:) = Magnetos;
            if size(app.magnetodata,1)>1
                plot(app.magnetoUIAxes,app.magnetodata);
            end
        end
        function MagnetoCallback(app,~,~)
            array = app.getframe(19);
            [frame,status] = Process(array,"in");
            if status
                %                 app.callingapp.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
                Magnetos(1)=typecast((uint8(frame( 4: 7))),'single');
                Magnetos(2)=typecast((uint8(frame( 8:11))),'single');
                Magnetos(3)=typecast((uint8(frame(12:15))),'single');
                app.dispMagneto(Magnetos);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         Sun Sensors     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function dispSunsensors(app,ssdata)
            app.SS1EditField.Value=num2str(ssdata(1));
            app.SS2EditField.Value=num2str(ssdata(2));
            app.SS3EditField.Value=num2str(ssdata(3));
            app.SS4EditField.Value=num2str(ssdata(4));
            app.SSdata(end+1,:) = ssdata;
            if size(app.SSdata,1)>1
                plot(app.SSUIAxes,app.SSdata);
            end

        end

        function SunsensorCallback(app,~,~)
            array = app.getframe(23);
            %             app.callingapp.msgLabel.Text = reshape(dec2hex(array,2)',1,[]);
            [frame,status] = Process(array,"in");
            if status
                ss =[];

                ss(1)=typecast((uint8(frame( 4: 7))),'single');
                ss(2)=typecast((uint8(frame( 8:11))),'single');
                ss(3)=typecast((uint8(frame(12:15))),'single');
                ss(4)=typecast((uint8(frame(16:19))),'single');
                %ss(5)=typecast((uint8(frame(20:23))),'single');
                app.dispSunsensors(ss);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%              Log        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function str = getString(~,frame)
            %
            switch frame(3)
                case 0
                    str = "PING";
                case 1
                    str = "INIT";
                case 2
                    str = "ACK";
                case 3
                    str = "NACK";
                    %                 case 4
                    %                 case 5
                    %                 case 6
                    %                 case 7
                    %                 case 8
                    %                 case 9
                case 10
                    str = "Get Angles";
                case 11
                    if frame(4)==255
                        str = "Get Gyro WIA";
                    else
                        str = "Get Gyro Data";
                    end
                case 12
                    if frame(4)==255
                        str = "Get Magneto WIA";
                    else
                        str = "Get Magneto Data";
                    end
                case 13
                    str = "Get Temperature";
                case 14
                    str = "Get Sun Sensors Data";
                case 15
                    str = "Get Telmetry";
                case 16
                    str = "Change Motors Speed";
                case 17
                    str = "Flash Write";
                case 18
                    str = "Flash Read";
                case 19
                    str = "Flash Erase";
                case 20
                    str = "Flash Unlock";
                case 21
                    str = "Flash Lock";
                case 22
                    str = "Read Log";
                case 23
                    str = "Get Motors Speed";
                case 24
                    str = "Start Latch";
                case 25
                    str = "Stop Latch";
                    %                 case 26

                otherwise
                    str = "error";
            end
        end
   
        function LogCallback(app,~,~)
            configureCallback(app.Serialdev,"off");
            
            array = app.getframe(7);
            
            [frame,status] = Process(array,"in");
            num = frame(4);
            
            if status
                for i=1:num
                    
                    array = app.getframe(7);
                    [frame,status] = Process(array,"in");
                    if status
                        app.cellArrayText = [app.cellArrayText sprintf("%d - " +app.getString(frame),(length(app.cellArrayText)+1))];
                        app.CommandsTextArea.Value = app.cellArrayText;
                scroll(app.CommandsTextArea, 'bottom');
                    end
                end
                
            end

        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           Memory        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function memorywrite(app,~,~)
            array = app.getframe(7);
            [~,status] = Process(array,"in");
            if status
                app.memLabel.Text = "Sent";
            end
        end
        function memoryread(app,~,~)
            array = app.getframe(11);
            dec2hex(array)
            [frame,status] = Process(array,"in");
            if status
                num = uint8(frame(4:7));
                z = typecast(num,'uint32');
                app.ValueEditField_2.Value = dec2hex(z,8);
            end

        end
        function unlock(app,~,~)
            array = app.getframe(7);
            [~,status] = Process(array,"in");
            if status
                app.memLabel.Text = "unlocked";
            end
        end
        function lock(app,~,~)
            array = app.getframe(7);
            [~,status] = Process(array,"in");
            if status
                app.memLabel.Text = "locked";
            end
        end
        function erase(app,~,~)
            array = app.getframe(7);
            [~,status] = Process(array,"in");
            if status
                app.memLabel.Text = "erased";
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           Motors        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function MotorsSetCallback(app,~,~)
            array = app.getframe(7);
            [frame,status] = Process(array,"in");
            if status
                if(frame(3) == 0x02)
                    app.MotorsLabel.FontColor = 'g';
                    app.MotorsLabel.Text = "Success";
                else
                    app.MotorsLabel.FontColor = 'r';
                    app.MotorsLabel.Text = "failed";
                end
            end
        end

        function MotorsGetCallback(app,~,~)
            array = app.getframe(10);
            EditFields = [app.Motor1EditField , app.Motor2EditField , app.Motor3EditField];
            [frame,status] = Process(array,"in");
            %             app.callingapp.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
            if status
                for i =1:3
                    if frame(i + 3) >= 128
                        temp = int8(frame(i + 3) - 128);
                        temp = -temp;
                        EditFields(i).Value = double(temp);
                    else
                        EditFields(i).Value = double(frame(i + 3));
                    end

                end
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        Temperature      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function TempCallback(app,~,~)

            array = app.getframe(11);
            [frame,status] = Process(array,"in");
            if status
                %                 app.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
                app.dispTemp(typecast((uint8(frame( 4: 7))),'single'));
            end
        end
        function dispTemp(app,temperature)
            app.Gauge.Value=temperature;
            app.tempEditField.Value = double(temperature);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           Combinations        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function MotorsSet_combineCallback(app,~,~)
            array = app.getframe(7);
            [frame,status] = Process(array,"in");
            if status
                if(frame(3) == 0x02)
                    app.MotorsLabel.FontColor = 'g';
                    app.MotorsLabel.Text = "Success";
                else
                    app.MotorsLabel.FontColor = 'r';
                    app.MotorsLabel.Text = "failed";
                end
            end
        end
        function TimerCallback_combine(app,~,~)
            configureCallback(app.Serialdev,"off");
            scale2  = [0x07,0x01,25];
            command2 = Process(scale2,"out");
            write(app.Serialdev,command2,"uint8");
            app.getframe(7);
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~,y]=system('wmic path win32_pnpentity get name /format:table |find "COM"');
            str = strsplit(y,'\n');
            str = strtrim(str);
            app.SerialPortDropDown.Items = str(1:end-1);
            app.SerialstateLabel.FontColor = 'r';
            app.SerialstateLabel.Text = "Disconnected";
            app.draw();
            ylim(app.SSUIAxes,[0 5]);
        end

        % Button pushed function: UpdateSerialButton
        function UpdateSerialButtonPushed(app, event)
            [~,y]=system('wmic path win32_pnpentity get name /format:table |find "COM"');
            str = strsplit(y,'\n');
            str = strtrim(str);
            app.SerialPortDropDown.Items = str(1:end-1);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            if isempty(app.Serialdev)
                serialname = convertStringsToChars(extractBetween( ...
                    app.SerialPortDropDown.Value,'(',')'));
                serialname = serialname{1};
                serialbaud = app.BaudRateEditField.Value;
                try
                    app.Serialdev = serialport(serialname,serialbaud);
                    configureCallback(app.Serialdev,"off");
                    command = [0x07,0x01,0x00];
                    command = Process(command,'out');
                    write(app.Serialdev,command,"uint8");
                    pause(0.1);
                    array = getframe(app,7);%read( app.Serialdev,7,"uint8");
                    ACK = uint8([0xC0 0x01 0x07 0x02 0xF5 0x0D 0xC0]);
                    if array == ACK
                        app.timerx = timer('TimerFcn',@(~,~)app.TimerCallback2,'StartDelay',2);
                        start(app.timerx);
                        app.SerialstateLabel.FontColor = 'g';
                        app.SerialstateLabel.Text = "Connected";
                        [y,Fs]=audioread('connection.mp3');
                        sound(y,Fs);
                        %%
                        command = [0x07,0x01,11,0xff];
                        command = Process(command,'out');
                        write(app.Serialdev,command,"uint8");
                        pause(0.1);
                        array = getframe(app,8);
                        app.gyroWIA = array(5);
                        app.gyroWIALabel.Text = dec2hex(app.gyroWIA);
                        command = [0x07,0x01,12,0xff];
                        command = Process(command,'out');
                        write(app.Serialdev,command,"uint8");
                        pause(0.1);
                        array = getframe(app,8);
                        app.magnetoWIA = array(5);
                        app.magnetoWIALabel.Text = dec2hex(app.magnetoWIA);
                        %%
                    else
                        app.SerialstateLabel.Text = "Could't Connect";
                    end
                catch
                    app.SerialstateLabel.Text = "Error : choose proper COM";
                end

            else
                app.Serialdev=[];
                app.SerialstateLabel.FontColor = 'r';
                app.SerialstateLabel.Text = "Disconnected";
                app.ConnectButton.Text = "Connect";
            end
        end

        % Button pushed function: PingButton
        function PingButtonPushed(app, event)
            command = [0x07,0x01,0x00];
            app.Lamp.Visible = 'off';
            app.send(command,@app.PingCallback,7);
            tic
            %             command = Process(command,"out");
            %             configureCallback(app.Serialdev,"terminator",@app.PingCallback);
            %             write(app.Serialdev,command,"uint8");
        end

        % Button pushed function: GyroscopeButton
        function GyroscopeButtonPushed(app, event)
            if app.GyroPanel.Visible
                app.GyroPanel.Visible = 0;
                app.remove(app.GyroPanel);
                app.rearrange();
            else
                app.gyrodata = [];
                app.add(app.GyroPanel);
                app.rearrange();
                app.GyroPanel.Visible = 1;

            end
        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: MagnetometerButton
        function MagnetometerButtonPushed(app, event)
            if app.MagnetoPanel.Visible
                app.MagnetoPanel.Visible = 0;
                app.remove(app.MagnetoPanel);
                app.rearrange();
            else
                app.magnetodata = [];
                %                 delete(app.magnetoUIAxes);
                app.add(app.MagnetoPanel);
                app.rearrange();
                app.MagnetoPanel.Visible = 1;

            end
        end

        % Button pushed function: SunsensorButton
        function SunsensorButtonPushed(app, event)
            if app.SunSensorsPanel.Visible
                app.SunSensorsPanel.Visible = 0;
                app.remove(app.SunSensorsPanel);
                app.rearrange();
            else
                app.SSdata = [];
                %                 delete(app.SSUIAxes);
                app.add(app.SunSensorsPanel);
                app.rearrange();
                app.SunSensorsPanel.Visible = 1;

            end
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            if app.LogPanel.Visible
                app.LogPanel.Visible = 0;
                app.remove(app.LogPanel);
                app.rearrange();
            else
                app.add(app.LogPanel);
                app.rearrange();
                app.LogPanel.Visible = 1;
                app.cellArrayText = [];
            end
        end

        % Button pushed function: LatchButton
        function LatchButtonPushed(app, event)
            if app.isLatched
                command = [0x07,0x01,25];
                app.isLatched = 0;
            else
                command = [0x07,0x01,24];
                app.isLatched = 1;
            end
            app.send(command,@app.LatchCallback,7);
        end

        % Button pushed function: MotorsButton
        function MotorsButtonPushed(app, event)
            if app.MotorsPanel.Visible
                app.MotorsPanel.Visible = 0;
                app.remove(app.MotorsPanel);
                app.rearrange();
            else
                app.add(app.MotorsPanel);
                app.rearrange();
                app.MotorsPanel.Visible = 1;
            end
        end

        % Button pushed function: MemoryButton
        function MemoryButtonPushed(app, event)
            if app.MemoryPanel.Visible
                app.MemoryPanel.Visible = 0;
                app.remove(app.MemoryPanel);
                app.rearrange();
            else
                app.add(app.MemoryPanel);
                app.rearrange();
                app.MemoryPanel.Visible = 1;
            end
        end

        % Button pushed function: AnglesButton
        function AnglesButtonPushed(app, event)
            if app.AnglesPanel.Visible
                app.AnglesPanel.Visible = 0;
                app.remove(app.AnglesPanel);
                app.rearrange();
            else
                app.draw();
                app.add(app.AnglesPanel);
                app.rearrange();
                app.AnglesPanel.Visible = 1;

            end
        end

        % Button pushed function: TempButton
        function TempButtonPushed(app, event)
            if app.TempPanel.Visible
                app.TempPanel.Visible = 0;
                app.remove(app.TempPanel);
                app.rearrange();
            else
                app.add(app.TempPanel);
                app.rearrange();
                app.TempPanel.Visible = 1;
            end
        end

        % Button pushed function: TelemetryButton
        function TelemetryButtonPushed(app, event)
            %             if ~isempty(app.AnglesHandle) && ~isempty(app.GyroscopeHandle)...
            %                     && ~isempty(app.MagnetometerHandle) && ~isempty(app.SunsensorsHandle)...
            %                     && ~isempty(app.LogHandle) && ~isempty(app.MemoryHandle)...
            %                     && ~isempty(app.MotorsHandle) && ~isempty(app.TemperatureHandle)
            %                 if app.isLatched == 0
            %                     if isempty(app.timerx)
            %                         app.timerx = timer('TimerFcn',@(~,~)app.TimerCallback,'Period',0.1);
            %                         app.timerx.TasksToExecute = inf;
            %                         app.timerx.ExecutionMode = 'fixedRate';
            %                         start(app.timerx);
            %                     else
            %                         stop(app.timerx);
            %                         app.timerx =[];
            %                     end
            %                 end
            %             else
            if app.AnglesPanel.Visible && app.GyroPanel.Visible...
                    && app.LogPanel.Visible && app.MemoryPanel.Visible...
                    && app.MotorsPanel.Visible && app.SunSensorsPanel.Visible...
                    && app.MagnetoPanel.Visible && app.TempPanel.Visible
                app.AnglesPanel.Visible = 0;
                app.GyroPanel.Visible = 0;
                app.MagnetoPanel.Visible = 0;
                app.SunSensorsPanel.Visible = 0;
                app.LogPanel.Visible = 0;
                app.MemoryPanel.Visible = 0;
                app.MotorsPanel.Visible = 0;
                app.TempPanel.Visible = 0;
                app.openpanelstable ={};
                %                 app.remove(app.AnglesPanel);
                %                 app.remove(app.GyroPanel);
                %                  app.remove(app.MagnetoPanel);
                %                  app.remove(app.SunSensorsPanel);
                %                  app.remove(app.LogPanel);
                %                  app.remove(app.MemoryPanel);
                %                  app.remove(app.MotorsPanel);
                %                  app.remove(app.TempPanel);
            else
                %                 delete([app.SSUIAxes app.gyroUIAxes app.magnetoUIAxes]);
                app.openpanelstable = {app.AnglesPanel,app.GyroPanel,app.MagnetoPanel,app.SunSensorsPanel...
                    ,app.LogPanel,app.MemoryPanel,app.MotorsPanel,app.TempPanel };
                app.rearrange();
                app.TempPanel.Visible = 1;
                app.MotorsPanel.Visible = 1;
                app.MemoryPanel.Visible = 1;
                app.LogPanel.Visible = 1;
                app.SunSensorsPanel.Visible = 1;
                app.MagnetoPanel.Visible = 1;
                app.GyroPanel.Visible = 1;
                app.AnglesPanel.Visible=1;
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            command = [0x07,0x01,10];
            app.send(command,@app.Anglecallback,19);

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.draw();
        end

        % Button pushed function: gyroReadButton
        function gyroReadButtonPushed(app, event)
            command = [0x07,0x01,11];
            app.send(command,@app.GyroCallback,19);
        end

        % Button pushed function: magnetoReadButton
        function magnetoReadButtonPushed(app, event)
            command = [0x07,0x01,12];
            app.send(command,@app.MagnetoCallback,19);
        end

        % Button pushed function: SSReadButton
        function SSReadButtonPushed(app, event)
            command = [0x07,0x01,14];
            app.send(command,@app.SunsensorCallback,23);
        end

        % Button pushed function: logReadButton
        function logReadButtonPushed(app, event)
            command = [0x07,0x01,22,0];
            app.send(command,@app.LogCallback,7);
            
            app.cellArrayText = [];
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            str = app.AddressEditField.Value;
            num = hex2num(str);
            arr = typecast(num,'uint8');
            address = arr(5:8);
            str = app.ValueEditField.Value;
            num = hex2num(str);
            arr = typecast(num,'uint8');
            val = arr(5:8);
            command = [0x07,0x01,17 address val];

            app.send(command,@app.memorywrite,7);
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
            command = [0x07,0x01,19 11];
            app.send(command,@app.erase,7);
        end

        % Button pushed function: UnlockButton
        function UnlockButtonPushed(app, event)
            command = [0x07,0x01,20 ];
            app.send(command,@app.unlock,7);
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            command = [0x07,0x01,21 ];
            app.send(command,@app.lock,7);
        end

        % Button pushed function: memReadButton
        function memReadButtonPushed(app, event)
            str = app.AddressEditField_2.Value;
            num = hex2num(str);
            arr = typecast(num,'uint8');
            address = arr(5:8);
            command = [0x07,0x01,18 address];
            app.send(command,@app.memoryread,11);
        end

        % Value changed function: SetMotor1EditField
        function SetMotor1EditFieldValueChanged(app, event)
            value = app.SetMotor1EditField.Value;
            app.Motor1Knob.Value = (value);
        end

        % Value changed function: SetMotor2EditField
        function SetMotor2EditFieldValueChanged(app, event)
            value = app.SetMotor2EditField.Value;
            app.Motor2Knob.Value = (value);
        end

        % Value changed function: SetMotor3EditField
        function SetMotor3EditFieldValueChanged(app, event)
            value = app.SetMotor3EditField.Value;
            app.Motor3Knob.Value = (value);
        end

        % Value changing function: Motor1Knob
        function Motor1KnobValueChanging(app, event)
            changingValue = double(int8(event.Value));
            app.SetMotor1EditField.Value = (changingValue);
        end

        % Value changing function: Motor2Knob
        function Motor2KnobValueChanging(app, event)
            changingValue = double(int8(event.Value));
            app.SetMotor2EditField.Value = (changingValue);
        end

        % Value changing function: Motor3Knob
        function Motor3KnobValueChanging(app, event)
            changingValue = double(int8(event.Value));
            app.SetMotor3EditField.Value = (changingValue);
        end

        % Button pushed function: ChangeSpeedButton
        function ChangeSpeedButtonPushed(app, event)
            scale = [0x07 0x01 16 0 0 0];
            m(1) = app.SetMotor1EditField.Value;
            m(2) = app.SetMotor2EditField.Value;
            m(3) = app.SetMotor3EditField.Value;
            for i =4:6
                if m(i-3) <  0
                    temp = int8(m(i-3));
                    temp = -temp;
                    scale(i) = uint8(temp) + 128;
                else
                    scale(i) = m(i-3);
                end
            end
            app.send(scale,@app.MotorsSetCallback,7);
        end

        % Button pushed function: GetSpeedButton
        function GetSpeedButtonPushed(app, event)
            scale = [0x07 0x01 23];
            app.send(scale,@app.MotorsGetCallback,10);
        end

        % Button pushed function: ReadTemperatureButton
        function ReadTemperatureButtonPushed(app, event)
            command = [0x07,0x01,13];
            app.send(command,@app.TempCallback,11);

        end

        % Button pushed function: getTeleButton
        function getTeleButtonPushed(app, event)
            command = [0x07,0x01,15];
            app.send(command,@app.TelmetryCallback,63);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try
                command = [0x07,0x01,0x00];
                command = Process(command,'out');
                write(app.Serialdev,command,"uint8");
                pause(0.1);
                array = getframe(app,7);
                ACK = uint8([0xC0 0x01 0x07 0x02 0xF5 0x0D 0xC0]);
                if array == ACK
                end
            catch
                app.openpanelstable ={};
                app.AnglesPanel.Visible = 0;
                app.GyroPanel.Visible = 0;
                app.MagnetoPanel.Visible = 0;
                app.SunSensorsPanel.Visible = 0;
                app.LogPanel.Visible = 0;
                app.MemoryPanel.Visible = 0;
                app.MotorsPanel.Visible = 0;
                app.TempPanel.Visible = 0;
                app.APPsPanel.Visible = 0;
                app.SerialstateLabel.FontColor ='r';
                app.SerialstateLabel.Text ="Disconnected";
                app.ConnectionPanel.Visible = 1;
                [y,Fs] = audioread("Termination.mp3");
                sound(y,Fs);
            end
        end

        % Button pushed function: Payload
        function PayloadButtonPushed(app, event)
            web("https://192.168.43.44/");
        end

        % Callback function
        function Motor2Knob_combineValueChanging(app, event)
            changingValue = double(int8(event.Value));
            app.SetMotor2EditField_combine.Value = (changingValue);
        end

        % Callback function
        function SetMotor2EditField_combineValueChanged(app, event)
            value = app.SetMotor2EditField_combine.Value;
            app.Motor2Knob_combine.Value = (value);
        end

        % Button pushed function: ChangespeedandreadButton
        function ChangespeedandreadButtonPushed(app, event)
            configureCallback(app.Serialdev,"off");
            scale   = [0x07 0x01 16 0 0 0];
            scale2  = [0x07,0x01,24];
            scale3  = [0x07,0x01,15];
            m(1) = app.SetMotor1EditField.Value;
            m(2) = app.SetMotor2EditField.Value;
            m(3) = app.SetMotor3EditField.Value;
            for i =4:6
                if m(i-3) <  0
                    temp = int8(m(i-3));
                    temp = -temp;
                    scale(i) = uint8(temp) + 128;
                else
                    scale(i) = m(i-3);
                end
            end
            command = Process(scale,"out");
            command2 = Process(scale2,"out");
            command3 = Process(scale3,"out");
            app.timer_combine = timer('TimerFcn',@(~,~)app.TimerCallback_combine,'StartDelay',2);
            start(app.timer_combine);
            write(app.Serialdev,command,"uint8");
            write(app.Serialdev,command2,"uint8");
            write(app.Serialdev,command3,"uint8");
            app.getframe(7);
            app.getframe(7);
            configureCallback(app.Serialdev,"byte",63,@app.TelmetryCallback);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.MainUIFigure.Icon = 'home.png';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.ConnectionPanel = uipanel(app.MainUIFigure);
            app.SerialPortDropDownLabel = uilabel(app.ConnectionPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.ConnectionPanel);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.BaudRateEditFieldLabel = uilabel(app.ConnectionPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.UpdateSerialButton = uibutton(app.ConnectionPanel, 'push');
            app.UpdateSerialButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSerialButtonPushed, true);
            app.UpdateSerialButton.Text = 'Update Serial';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectionLabel = uilabel(app.ConnectionPanel);
            app.ConnectionLabel.Text = 'Connection';
            app.StatusLabel = uilabel(app.ConnectionPanel);
            app.StatusLabel.Text = 'Status :';
            app.SerialstateLabel = uilabel(app.ConnectionPanel);
            app.SerialstateLabel.Text = '';
            app.APPsPanel = uipanel(app.MainUIFigure);
            app.APPsPanel.Visible = 'off';
            app.GyroscopeButton = uibutton(app.APPsPanel, 'push');
            app.GyroscopeButton.ButtonPushedFcn = createCallbackFcn(app, @GyroscopeButtonPushed, true);
            app.GyroscopeButton.Icon = 'Gyroscope.png';
            app.GyroscopeButton.Text = '';
            app.MagnetometerButton = uibutton(app.APPsPanel, 'push');
            app.MagnetometerButton.ButtonPushedFcn = createCallbackFcn(app, @MagnetometerButtonPushed, true);
            app.MagnetometerButton.Icon = 'Magneto.png';
            app.MagnetometerButton.Text = '';
            app.SunsensorButton = uibutton(app.APPsPanel, 'push');
            app.SunsensorButton.ButtonPushedFcn = createCallbackFcn(app, @SunsensorButtonPushed, true);
            app.SunsensorButton.Icon = 'SunSensors.png';
            app.SunsensorButton.Text = '';
            app.MemoryButton = uibutton(app.APPsPanel, 'push');
            app.MemoryButton.ButtonPushedFcn = createCallbackFcn(app, @MemoryButtonPushed, true);
            app.MemoryButton.Icon = 'Memory.png';
            app.MemoryButton.Text = '';
            app.LogButton = uibutton(app.APPsPanel, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Icon = 'Log.png';
            app.LogButton.Text = '';
            app.LatchButton = uibutton(app.APPsPanel, 'push');
            app.LatchButton.ButtonPushedFcn = createCallbackFcn(app, @LatchButtonPushed, true);
            app.LatchButton.Icon = 'Latch-close.png';
            app.LatchButton.Text = '';
            app.AnglesButton = uibutton(app.APPsPanel, 'push');
            app.AnglesButton.ButtonPushedFcn = createCallbackFcn(app, @AnglesButtonPushed, true);
            app.AnglesButton.Icon = 'Simulator.png';
            app.AnglesButton.Text = '';
            app.TempButton = uibutton(app.APPsPanel, 'push');
            app.TempButton.ButtonPushedFcn = createCallbackFcn(app, @TempButtonPushed, true);
            app.TempButton.Icon = 'Temperature.png';
            app.TempButton.Text = '';
            app.TelemetryButton = uibutton(app.APPsPanel, 'push');
            app.TelemetryButton.ButtonPushedFcn = createCallbackFcn(app, @TelemetryButtonPushed, true);
            app.TelemetryButton.Icon = 'Telemetry.png';
            app.TelemetryButton.Text = '';
            app.PingButton = uibutton(app.APPsPanel, 'push');
            app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
            app.PingButton.Icon = 'Ping.png';
            app.PingButton.Text = '';
            app.Lamp = uilamp(app.APPsPanel);
            app.Lamp.Visible = 'off';
            app.MotorsButton = uibutton(app.APPsPanel, 'push');
            app.MotorsButton.ButtonPushedFcn = createCallbackFcn(app, @MotorsButtonPushed, true);
            app.MotorsButton.Icon = 'Motors.png';
            app.MotorsButton.Text = '';
            app.getTeleButton = uibutton(app.APPsPanel, 'push');
            app.getTeleButton.ButtonPushedFcn = createCallbackFcn(app, @getTeleButtonPushed, true);
            app.getTeleButton.Icon = 'get tel.png';
            app.getTeleButton.Text = '';
            app.Button = uibutton(app.APPsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'connection.png';
            app.Button.Text = '';
            app.EditField = uieditfield(app.APPsPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Visible = 'off';
            app.msLabel = uilabel(app.APPsPanel);
            app.msLabel.Visible = 'off';
            app.msLabel.Text = 'ms';
            app.Payload = uibutton(app.APPsPanel, 'push');
            app.Payload.ButtonPushedFcn = createCallbackFcn(app, @PayloadButtonPushed, true);
            app.Payload.Icon = 'image-removebg-preview.png';
            app.Payload.Text = '';
            app.TempPanel = uipanel(app.MainUIFigure);
            app.TempPanel.Visible = 'off';
            app.Gauge = uigauge(app.TempPanel, 'circular');
            app.Gauge.Limits = [30 50];
            app.Gauge.Value = 30;
            app.tempEditField = uieditfield(app.TempPanel, 'numeric');
            app.ReadTemperatureButton = uibutton(app.TempPanel, 'push');
            app.ReadTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTemperatureButtonPushed, true);
            app.ReadTemperatureButton.Text = 'Read Temperature';
            app.TemperatureLabel = uilabel(app.TempPanel);
            app.TemperatureLabel.Text = 'Temperature';
            app.Image5 = uiimage(app.TempPanel);
            app.Image5.ImageSource = 'Temperature.png';
            app.MagnetoPanel = uipanel(app.MainUIFigure);
            app.MagnetoPanel.Visible = 'off';
            app.magnetoUIAxes = uiaxes(app.MagnetoPanel);
            title(app.magnetoUIAxes, 'Magnetometer')
            app.magnetoUIAxes.Tag = 'Magnetometer';
            app.magnetoReadButton = uibutton(app.MagnetoPanel, 'push');
            app.magnetoReadButton.ButtonPushedFcn = createCallbackFcn(app, @magnetoReadButtonPushed, true);
            app.magnetoReadButton.Text = 'Read';
            app.xEditFieldLabel_2 = uilabel(app.MagnetoPanel);
            app.xEditFieldLabel_2.Text = 'x';
            app.magnetoxEditField = uieditfield(app.MagnetoPanel, 'text');
            app.yEditFieldLabel_2 = uilabel(app.MagnetoPanel);
            app.yEditFieldLabel_2.Text = 'y';
            app.magnetoyEditField = uieditfield(app.MagnetoPanel, 'text');
            app.zEditFieldLabel_2 = uilabel(app.MagnetoPanel);
            app.zEditFieldLabel_2.Text = 'z';
            app.magnetozEditField = uieditfield(app.MagnetoPanel, 'text');
            app.Image3 = uiimage(app.MagnetoPanel);
            app.Image3.ImageSource = 'Magneto.png';
            app.magnetoWIALabel = uilabel(app.MagnetoPanel);
            app.magnetoWIALabel.Text = '';
            app.SunSensorsPanel = uipanel(app.MainUIFigure);
            app.SunSensorsPanel.Visible = 'off';
            app.SSUIAxes = uiaxes(app.SunSensorsPanel);
            title(app.SSUIAxes, 'Sun Sensors')
            app.SSUIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.SSUIAxes.ZTick = [0 1 2 3 4 5];
            app.SSUIAxes.LineStyleOrder = {'-'};
            app.SSReadButton = uibutton(app.SunSensorsPanel, 'push');
            app.SSReadButton.ButtonPushedFcn = createCallbackFcn(app, @SSReadButtonPushed, true);
            app.SSReadButton.Text = 'Read';
            app.SS1EditFieldLabel = uilabel(app.SunSensorsPanel);
            app.SS1EditFieldLabel.Text = 'SS1';
            app.SS1EditField = uieditfield(app.SunSensorsPanel, 'text');
            app.SS2EditFieldLabel = uilabel(app.SunSensorsPanel);
            app.SS2EditFieldLabel.Text = 'SS2';
            app.SS2EditField = uieditfield(app.SunSensorsPanel, 'text');
            app.SS3EditFieldLabel = uilabel(app.SunSensorsPanel);
            app.SS3EditFieldLabel.Text = 'SS3';
            app.SS3EditField = uieditfield(app.SunSensorsPanel, 'text');
            app.SS4EditFieldLabel = uilabel(app.SunSensorsPanel);
            app.SS4EditFieldLabel.Text = 'SS4';
            app.SS4EditField = uieditfield(app.SunSensorsPanel, 'text');
            app.Image4 = uiimage(app.SunSensorsPanel);
            app.Image4.ImageSource = 'SunSensors.png';
            app.MotorsPanel = uipanel(app.MainUIFigure);
            app.MotorsPanel.Visible = 'off';
            app.TabGroup_2 = uitabgroup(app.MotorsPanel);
            app.SettingTab = uitab(app.TabGroup_2);
            app.SettingTab.Title = 'Setting';
            app.Motor1KnobLabel = uilabel(app.SettingTab);
            app.Motor1KnobLabel.Text = 'Motor1';
            app.Motor1Knob = uiknob(app.SettingTab, 'continuous');
            app.Motor1Knob.Limits = [-100 100];
            app.Motor1Knob.ValueChangingFcn = createCallbackFcn(app, @Motor1KnobValueChanging, true);
            app.Motor2KnobLabel = uilabel(app.SettingTab);
            app.Motor2KnobLabel.Text = 'Motor2';
            app.Motor2Knob = uiknob(app.SettingTab, 'continuous');
            app.Motor2Knob.Limits = [-100 100];
            app.Motor2Knob.ValueChangingFcn = createCallbackFcn(app, @Motor2KnobValueChanging, true);
            app.Motor3KnobLabel = uilabel(app.SettingTab);
            app.Motor3KnobLabel.Text = 'Motor3';
            app.Motor3Knob = uiknob(app.SettingTab, 'continuous');
            app.Motor3Knob.Limits = [-100 100];
            app.Motor3Knob.ValueChangingFcn = createCallbackFcn(app, @Motor3KnobValueChanging, true);
            app.ChangeSpeedButton = uibutton(app.SettingTab, 'push');
            app.ChangeSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSpeedButtonPushed, true);
            app.ChangeSpeedButton.Text = 'Change Speed';
            app.Motor1EditField_2Label = uilabel(app.SettingTab);
            app.Motor1EditField_2Label.Text = 'Motor 1';
            app.SetMotor1EditField = uieditfield(app.SettingTab, 'numeric');
            app.SetMotor1EditField.Limits = [-100 100];
            app.SetMotor1EditField.ValueDisplayFormat = '%.0f';
            app.SetMotor1EditField.ValueChangedFcn = createCallbackFcn(app, @SetMotor1EditFieldValueChanged, true);
            app.Motor2EditField_2Label = uilabel(app.SettingTab);
            app.Motor2EditField_2Label.Text = 'Motor 2';
            app.SetMotor2EditField = uieditfield(app.SettingTab, 'numeric');
            app.SetMotor2EditField.Limits = [-100 100];
            app.SetMotor2EditField.ValueDisplayFormat = '%.0f';
            app.SetMotor2EditField.ValueChangedFcn = createCallbackFcn(app, @SetMotor2EditFieldValueChanged, true);
            app.Motor3EditField_2Label = uilabel(app.SettingTab);
            app.Motor3EditField_2Label.Text = 'Motor3';
            app.SetMotor3EditField = uieditfield(app.SettingTab, 'numeric');
            app.SetMotor3EditField.Limits = [-100 100];
            app.SetMotor3EditField.ValueDisplayFormat = '%.0f';
            app.SetMotor3EditField.ValueChangedFcn = createCallbackFcn(app, @SetMotor3EditFieldValueChanged, true);
            app.MotorsLabel = uilabel(app.SettingTab);
            app.MotorsLabel.Text = '';
            app.ChangespeedandreadButton = uibutton(app.SettingTab, 'push');
            app.ChangespeedandreadButton.ButtonPushedFcn = createCallbackFcn(app, @ChangespeedandreadButtonPushed, true);
            app.ChangespeedandreadButton.Text = 'Change speed and read';
            app.GettingTab = uitab(app.TabGroup_2);
            app.GettingTab.Title = 'Getting';
            app.Motor1EditFieldLabel = uilabel(app.GettingTab);
            app.Motor1EditFieldLabel.Text = 'Motor1';
            app.Motor1EditField = uieditfield(app.GettingTab, 'numeric');
            app.Motor1EditField.Limits = [-100 100];
            app.Motor1EditField.ValueDisplayFormat = '%.0f';
            app.Motor2EditFieldLabel = uilabel(app.GettingTab);
            app.Motor2EditFieldLabel.Text = 'Motor2';
            app.Motor2EditField = uieditfield(app.GettingTab, 'numeric');
            app.Motor2EditField.Limits = [-100 100];
            app.Motor2EditField.ValueDisplayFormat = '%.0f';
            app.Motor3EditFieldLabel = uilabel(app.GettingTab);
            app.Motor3EditFieldLabel.Text = 'Motor3';
            app.Motor3EditField = uieditfield(app.GettingTab, 'numeric');
            app.Motor3EditField.Limits = [-100 100];
            app.Motor3EditField.ValueDisplayFormat = '%.0f';
            app.GetSpeedButton = uibutton(app.GettingTab, 'push');
            app.GetSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @GetSpeedButtonPushed, true);
            app.GetSpeedButton.Text = 'Get Speed';
            app.MemoryPanel = uipanel(app.MainUIFigure);
            app.MemoryPanel.Visible = 'off';
            app.TabGroup = uitabgroup(app.MemoryPanel);
            app.WriteTab = uitab(app.TabGroup);
            app.WriteTab.Title = 'Write';
            app.AddressEditFieldLabel = uilabel(app.WriteTab);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.WriteTab, 'text');
            app.ValueEditFieldLabel = uilabel(app.WriteTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.WriteTab, 'text');
            app.WriteButton = uibutton(app.WriteTab, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.memLabel = uilabel(app.WriteTab);
            app.memLabel.Text = '';
            app.UnlockButton = uibutton(app.WriteTab, 'push');
            app.UnlockButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockButtonPushed, true);
            app.UnlockButton.Text = 'Unlock';
            app.LockButton = uibutton(app.WriteTab, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Text = 'Lock';
            app.EraseButton = uibutton(app.WriteTab, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Text = 'Erase';
            app.x080E00000x080FFFFFLabel = uilabel(app.WriteTab);
            app.x080E00000x080FFFFFLabel.Text = '0x080E 0000  :  0x080F FFFF';
            app.ReadTab = uitab(app.TabGroup);
            app.ReadTab.Title = 'Read';
            app.memReadButton = uibutton(app.ReadTab, 'push');
            app.memReadButton.ButtonPushedFcn = createCallbackFcn(app, @memReadButtonPushed, true);
            app.memReadButton.Text = 'Read';
            app.AddressEditField_2Label = uilabel(app.ReadTab);
            app.AddressEditField_2Label.Text = 'Address';
            app.AddressEditField_2 = uieditfield(app.ReadTab, 'text');
            app.ValueEditField_2Label = uilabel(app.ReadTab);
            app.ValueEditField_2Label.Text = 'Value';
            app.ValueEditField_2 = uieditfield(app.ReadTab, 'text');
            app.LogPanel = uipanel(app.MainUIFigure);
            app.LogPanel.Visible = 'off';
            app.CommandsTextArea = uitextarea(app.LogPanel);
            app.CommandsTextArea.Editable = 'off';
            app.logReadButton = uibutton(app.LogPanel, 'push');
            app.logReadButton.ButtonPushedFcn = createCallbackFcn(app, @logReadButtonPushed, true);
            app.logReadButton.Text = 'Read';
            app.GyroPanel = uipanel(app.MainUIFigure);
            app.GyroPanel.Visible = 'off';
            app.gyroUIAxes = uiaxes(app.GyroPanel);
            title(app.gyroUIAxes, {'GyroScope'; ''})
            app.gyroReadButton = uibutton(app.GyroPanel, 'push');
            app.gyroReadButton.ButtonPushedFcn = createCallbackFcn(app, @gyroReadButtonPushed, true);
            app.gyroReadButton.Text = 'Read';
            app.xEditFieldLabel = uilabel(app.GyroPanel);
            app.xEditFieldLabel.Text = 'x';
            app.gyroxEditField = uieditfield(app.GyroPanel, 'text');
            app.yEditFieldLabel = uilabel(app.GyroPanel);
            app.yEditFieldLabel.Text = 'y';
            app.gyroyEditField = uieditfield(app.GyroPanel, 'text');
            app.zEditFieldLabel = uilabel(app.GyroPanel);
            app.zEditFieldLabel.Text = 'z';
            app.gyrozEditField = uieditfield(app.GyroPanel, 'text');
            app.Image2 = uiimage(app.GyroPanel);
            app.Image2.ImageSource = 'Gyroscope.png';
            app.gyroWIALabel = uilabel(app.GyroPanel);
            app.gyroWIALabel.Text = '';
            app.AnglesPanel = uipanel(app.MainUIFigure);
            app.AnglesPanel.Visible = 'off';
            app.SimulatorUIFigure = uiaxes(app.AnglesPanel);
            title(app.SimulatorUIFigure, 'Simulator')
            app.SimulateButton = uibutton(app.AnglesPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ResetButton = uibutton(app.AnglesPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.AnglesPanel);
            app.Image.ImageSource = 'Simulator.png';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end